classdef SolarApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        LoadGauge             matlab.ui.control.LinearGauge
        LoadkLabel            matlab.ui.control.Label
        PlotPIVButton         matlab.ui.control.Button
        PlotVoltageButton     matlab.ui.control.StateButton
        CommandsListBox       matlab.ui.control.ListBox
        CommandsListBoxLabel  matlab.ui.control.Label
        ExecuteButton         matlab.ui.control.Button
        InputEditField        matlab.ui.control.NumericEditField
        InputEditFieldLabel   matlab.ui.control.Label
        StatusTextArea        matlab.ui.control.TextArea
        PIVAxes               matlab.ui.control.UIAxes
        VoltageUIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        solar = solarObj; % solar object
        textAreaVal = ""; % string displayed in the StatusTextArea
        currCmd; % a cmd object, current command selected in commands list box 
        voltageArr; % an array to store voltage readings
    end
    
    methods (Access = private)
        
        function displayText(app, message)
            app.textAreaVal = app.textAreaVal + message;
            app.StatusTextArea.Value = app.textAreaVal;
            scroll(app.StatusTextArea, 'bottom')
        end
        
        function hideAllButtons(app)
            app.CommandsListBox.Visible = "off";
            app.CommandsListBoxLabel.Visible = "off";
            app.InputEditField.Visible = "off";
            app.InputEditFieldLabel.Visible = "off";
            app.ExecuteButton.Visible = "off";
            app.PlotVoltageButton.Visible = "off";
            app.PlotPIVButton.Visible = "off";
        end
        
        function showAllButtons(app)
            app.CommandsListBox.Visible = "on";
            app.CommandsListBoxLabel.Visible = "on";
            app.InputEditField.Visible = "on";
            app.InputEditFieldLabel.Visible = "on";
            app.ExecuteButton.Visible = "on";
            app.PlotVoltageButton.Visible = "on";
            app.PlotPIVButton.Visible = "on";
        end

        function turnOffInputEditField(app)
            app.InputEditField.Enable = "off";
            app.InputEditField.Editable = "off";
        end

        function turnOnInputEditField(app)
            app.InputEditField.Enable = "on";
            app.InputEditField.Editable = "on";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize textarea
            app.displayText("* Initializing App..." + newline);
            % hide all buttons to ensure user can't interrupt the startup
            app.hideAllButtons(); 
            % register solar module
            moduleList = regReviGridObjs; % return a list of module objects
            moduleListSize = size(moduleList);
            foundSolar = false;
            % check if a solar tracker module is connected
            for i = 1:moduleListSize(2)
                currModule = moduleList{i};
                if currModule.ID == "solartracker"
                    foundSolar = true;
                    app.solar = currModule;
                end
            end
            % if no solar module is connected
            if ~foundSolar
                app.displayText("* Can't find Solar Panel Module." + newline);
                return;
            end
            pause(5);
            % initialize other components
            app.displayText("* Found Solar Panel Module" + newline);
            app.CommandsListBox.Items = keys(app.solar.cmds);
            app.CommandsListBox.Tooltip = app.solar.cmdMenu(app.CommandsListBox.Value);
            app.showAllButtons();
            % disenable the text field for now
            % only enable it when the selected command needs input
            app.turnOffInputEditField(); 
            app.currCmd = app.solar.cmds("init");
            app.InputEditField.AllowEmpty = 'on';
            app.InputEditField.Value = ''; % clear edit field
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            app.displayText("* " + app.currCmd.name + newline)
            
            % send out cmd
            if app.currCmd.out ~= 0 % if cmd needs user input
                % print prompt
                if app.currCmd.prompts == "unknown: "
                    str = " - input: ";
                else 
                    str = "  - " + app.currCmd.prompts(1);
                end
                app.displayText(str);
                % check user input
                value = string(app.InputEditField.Value);
                % check if user input is valid
                validFlag = app.solar.checkValid(app.currCmd, str2double(value));
                if validFlag == 0 % is invalid
                    app.displayText("Invalid Input!");
                    app.displayText(newline);
                    return;
                end
    
                % display valid input in statusTextArea
                app.displayText(str2double(value));
                app.displayText(newline);
                % send out cmd
                app.solar.call(app.currCmd.name, str2double(value));
                pause(0.01);
                app.InputEditField.Value = ''; % clear edit field
            elseif app.currCmd.in ~= 0 % if cmd takes input from Arduino
                % send out cmd
                retVal = app.solar.call(app.currCmd.name);
       
                % update text area
                numOfInput = app.currCmd.in;
                for i = 1:numOfInput
                    str = "  > " + retVal(i) + newline;
                    app.displayText(str);
                end
            else % cmd w/o input and output
                app.solar.call(app.currCmd.name);
            end % if, execute diff types of cmds
        end

        % Value changed function: CommandsListBox
        function CommandsListBoxValueChanged(app, event)
            value = app.CommandsListBox.Value;
            app.currCmd = app.solar.cmds(string(value));
            if app.currCmd.out ~= 0 % if cmd needs user input
                app.turnOnInputEditField(); % enable edit field
            else
                app.turnOffInputEditField(); % disable edit field
            end

        end

        % Value changed function: PlotVoltageButton
        function PlotVoltageButtonValueChanged(app, event)
            if(app.PlotVoltageButton.Value == 1)
                app.displayText("* Plotting voltage readings...");
                app.displayText(newline);
            else
                app.displayText("* Voltage reading paused");
                app.displayText(newline);
            end

            while(app.PlotVoltageButton.Value == 1) 
                currVal = app.solar.call("getVal");
                currVal = str2double(currVal);
                currVal = currVal * (5/1023); % convert to volts
                voltageArrSize = size(app.voltageArr);
                voltageArrSize = voltageArrSize(2);
                % display 100 voltage readings at a time
                if voltageArrSize < 100 % the array has less than 100 values
                    app.voltageArr(voltageArrSize + 1) = currVal;
                else % the array has 100 values
                    app.voltageArr = circshift(app.voltageArr, -1);
                    app.voltageArr(100) = currVal;
                end
                % resize the y axis
                if max(app.voltageArr) >= 1
                    app.VoltageUIAxes.YLim = [0, max(app.voltageArr) + 0.2];
                else
                    app.VoltageUIAxes.YLim = [0, 1];
                end
                plot(app.VoltageUIAxes, app.voltageArr);
            end
        end

        % Button pushed function: PlotPIVButton
        function PlotPIVButtonPushed(app, event)
            % disable the plotVoltageButton before ploting PIV
            if app.PlotVoltageButton.Value == 1
                app.displayText("* Voltage plot paused");
                app.displayText(newline);
            end
            app.PlotVoltageButton.Value == 0;
            app.PlotVoltageButton.Enable = "off";
            % disable the executeButton and plotPIVButton
            app.ExecuteButton.Enable = "off";
            app.PlotPIVButton.Enable = "off";
            % send the runIVScan command to the module
            app.solar.writeSP("runIVScan");
            % read table header
            returnedVal = readline(app.solar.SP);
            app.displayText("* Ploting PIV Curve..." + newline);

            % read all 255 lines of the returned table
            for i = 1:255
                % read one row
                returnedVal(i) = readline(app.solar.SP);
                % get resistance, voltage, current and power from the row
                splitStr = strsplit(returnedVal(i), ' , ');
                resistance(i) = str2double(splitStr(1));
                voltage(i) = str2double(splitStr(2));
                current(i) = str2double(splitStr(3));
                power(i) = str2double(splitStr(4));
                disp(resistance(i))
                disp(voltage(i))
                disp(current(i))
                disp(power(i))
                disp(newline)
                
                % scale the PIV Axes
                maxX = max(voltage);
                maxCurrent = max(current);
                maxPower = max(power);
                maxY = max(maxCurrent, maxPower);
                xRangeMax = maxX + maxX * 0.05;
                yRangeMax = maxY + maxY * 0.05;
                app.PIVAxes.XLim = [0, xRangeMax];
                app.PIVAxes.YLim = [0, yRangeMax];

                % update the loadGauge
                app.LoadGauge.Value = resistance(i) / 1000;
                % plot
                plot(app.PIVAxes, voltage, current);
                hold(app.PIVAxes, 'on');
                plot(app.PIVAxes, voltage, power);
                hold(app.PIVAxes, 'on');
                plot(app.PIVAxes, voltage(i), current(i),'o','markerSize',10);
                hold(app.PIVAxes, 'on');
                plot(app.PIVAxes, voltage(i), power(i),'o','markerSize',10);
                hold(app.PIVAxes, 'off');
                legend(app.PIVAxes, 'current', 'power', 'Location', 'northwest');
            end
            % plot the last point
            plot(app.PIVAxes, voltage, current);
            hold(app.PIVAxes, 'on');
            plot(app.PIVAxes, voltage, power);
            hold(app.PIVAxes, 'off');
            returnedVal = readline(app.solar.SP); % read eod 
            returnedVal = readline(app.solar.SP); % read 255 
            % set axes legend
            legend(app.PIVAxes, 'current', 'power', 'Location', 'northeast');
            % enable the buttons
            app.PlotVoltageButton.Enable = "on";
            app.ExecuteButton.Enable = "on";
            app.PlotPIVButton.Enable = "on";
            app.displayText("* PIV Curve is done" + newline);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
               app.solar.call("off");
           catch
               delete(app)
           end
           delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.VoltageUIAxes = uiaxes(app.UIFigure);
            title(app.VoltageUIAxes, 'Solar Panel Voltage')
            xlabel(app.VoltageUIAxes, 'Time Index')
            ylabel(app.VoltageUIAxes, 'Voltage (V)')
            zlabel(app.VoltageUIAxes, 'Z')
            app.PIVAxes = uiaxes(app.UIFigure);
            title(app.PIVAxes, 'Current and Power vs Voltage')
            xlabel(app.PIVAxes, 'Voltage (V)')
            ylabel(app.PIVAxes, 'Current (mA), Power (mW)')
            zlabel(app.PIVAxes, 'Z')
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.InputEditFieldLabel = uilabel(app.UIFigure);
            app.InputEditFieldLabel.Text = 'Input';
            app.InputEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.CommandsListBoxLabel = uilabel(app.UIFigure);
            app.CommandsListBoxLabel.Text = 'Commands';
            app.CommandsListBox = uilistbox(app.UIFigure);
            app.CommandsListBox.ValueChangedFcn = createCallbackFcn(app, @CommandsListBoxValueChanged, true);
            app.PlotVoltageButton = uibutton(app.UIFigure, 'state');
            app.PlotVoltageButton.ValueChangedFcn = createCallbackFcn(app, @PlotVoltageButtonValueChanged, true);
            app.PlotVoltageButton.Text = 'Plot Voltage';
            app.PlotPIVButton = uibutton(app.UIFigure, 'push');
            app.PlotPIVButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPIVButtonPushed, true);
            app.PlotPIVButton.Text = 'Plot PIV';
            app.LoadkLabel = uilabel(app.UIFigure);
            app.LoadkLabel.Text = 'Load (kΩ)';
            app.LoadGauge = uigauge(app.UIFigure, 'linear');
            app.LoadGauge.Limits = [1.2 101.2];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SolarApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end